classdef Equalizer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel1         matlab.ui.container.Panel
        text129          matlab.ui.control.Label
        songnumber       matlab.ui.control.Label
        text127          matlab.ui.control.Label
        roundnumber      matlab.ui.control.Label
        text125          matlab.ui.control.Label
        slider3          matlab.ui.control.Slider
        name             matlab.ui.control.EditField
        text121          matlab.ui.control.Label
        text120          matlab.ui.control.Label
        text119          matlab.ui.control.Label
        text118          matlab.ui.control.Label
        text117          matlab.ui.control.Label
        text116          matlab.ui.control.Label
        text115          matlab.ui.control.Label
        text114          matlab.ui.control.Label
        text113          matlab.ui.control.Label
        text112          matlab.ui.control.Label
        text111          matlab.ui.control.Label
        text110          matlab.ui.control.Label
        instruction      matlab.ui.control.Label
        text105          matlab.ui.control.Label
        text104          matlab.ui.control.Label
        text103          matlab.ui.control.Label
        text102          matlab.ui.control.Label
        text101          matlab.ui.control.Label
        text100          matlab.ui.control.Label
        text99           matlab.ui.control.Label
        text95           matlab.ui.control.Label
        text94           matlab.ui.control.Label
        text93           matlab.ui.control.Label
        text92           matlab.ui.control.Label
        text91           matlab.ui.control.Label
        text90           matlab.ui.control.Label
        text89           matlab.ui.control.Label
        text85           matlab.ui.control.Label
        text84           matlab.ui.control.Label
        text83           matlab.ui.control.Label
        text82           matlab.ui.control.Label
        text81           matlab.ui.control.Label
        text80           matlab.ui.control.Label
        text79           matlab.ui.control.Label
        text75           matlab.ui.control.Label
        text74           matlab.ui.control.Label
        text73           matlab.ui.control.Label
        text72           matlab.ui.control.Label
        text71           matlab.ui.control.Label
        text70           matlab.ui.control.Label
        text69           matlab.ui.control.Label
        text65           matlab.ui.control.Label
        text64           matlab.ui.control.Label
        text63           matlab.ui.control.Label
        text62           matlab.ui.control.Label
        text61           matlab.ui.control.Label
        text60           matlab.ui.control.Label
        text59           matlab.ui.control.Label
        slider12         matlab.ui.control.Slider
        slider11         matlab.ui.control.Slider
        slider10         matlab.ui.control.Slider
        slider9          matlab.ui.control.Slider
        slider8          matlab.ui.control.Slider
        slider7          matlab.ui.control.Slider
        slider6          matlab.ui.control.Slider
        text48           matlab.ui.control.Label
        text47           matlab.ui.control.Label
        text46           matlab.ui.control.Label
        text45           matlab.ui.control.Label
        text44           matlab.ui.control.Label
        text43           matlab.ui.control.Label
        text42           matlab.ui.control.Label
        text41           matlab.ui.control.Label
        text40           matlab.ui.control.Label
        text38           matlab.ui.control.Label
        slider5          matlab.ui.control.Slider
        slider4          matlab.ui.control.Slider
        Initialize       matlab.ui.control.Button
        CloseButton      matlab.ui.control.Button
        SubmitButton     matlab.ui.control.Button
        text30           matlab.ui.control.Label
        text29           matlab.ui.control.Label
        text28           matlab.ui.control.Label
        text17           matlab.ui.control.Label
        text16           matlab.ui.control.Label
        text15           matlab.ui.control.Label
        text14           matlab.ui.control.Label
        text13           matlab.ui.control.Label
        text12           matlab.ui.control.Label
        text11           matlab.ui.control.Label
        text10           matlab.ui.control.Label
        text9            matlab.ui.control.Label
        gainleft         matlab.ui.control.Label
        text8            matlab.ui.control.Label
        text6            matlab.ui.control.Label
        text5            matlab.ui.control.Label
        text4            matlab.ui.control.Label
        StopButton       matlab.ui.control.Button
        ResetGainButton  matlab.ui.control.Button
        slider1          matlab.ui.control.Slider
        slider2          matlab.ui.control.Slider
        PlayButton       matlab.ui.control.Button
    end

    
    methods (Access = private)
        function edit1_CreateFcn(app, hObject, ~, ~)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit2_CreateFcn(app, hObject, ~, ~)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit3_CreateFcn(app, hObject, ~, ~)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function slider13_CreateFcn(app, hObject, ~, ~)
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider14_CreateFcn(app, hObject, ~, ~)
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider15_CreateFcn(app, hObject, ~, ~)
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Equalizer_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Equalizer (see VARARGIN)
            
            % Choose default command line output for Equalizer
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: CloseButton
        function CloseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set_param('fifteenbands','SimulationCommand','stop');
            close;
        end

        % Button pushed function: Initialize
        function Initialize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.PlayButton,'Enable','on');
            set(handles.Initialize,'Enable','off');
            clc;
            % hObject    handle to initialize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global i songs totaltrack rounds songFolder
            rounds = 1;
            assignin('base','rounds',rounds);
            totaltrack = 2;
            songFolder = [pwd '\SongFolder\'];
            songList = dir([songFolder '*.wav']);
            f = fieldnames(songList);
            ii = 1;
            songListFilter = struct(f{:});
            for j = 1:1:length(songList)
                if ~strcmp(songList(j).name, 'whitenoise.wav')
                    songListFilter(ii).name = songList(j).name;
                    songListFilter(ii).date = songList(j).date;
                    songListFilter(ii).isdir = songList(j).isdir;
                    ii = ii + 1;
                end
            end
            songListFilter = songListFilter(randperm(length(songListFilter)))'; %randomise
            %while length(songListFilter) < totaltrack
            %    songListFilter = [songListFilter; songListFilter];
            %end
            songs = {};
            songs{1} = [songFolder 'whitenoise.wav'];
            for j = 1:1:totaltrack
                songs{j+1} = [songFolder songListFilter(j).name];
            end
            songs{j+2} = [songFolder 'whitenoise.wav'];
            i = 1;
            set_param('fifteenbands/From Multimedia File','Filename',songs{i});
            set(handles.instruction,'String','System has been initialized, click ''Play'' to start.');
        end

        % Button pushed function: PlayButton
        function PlayButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PlayButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global gainleft gainlimit rounds i
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            global freq1 freq2 freq3 freq4
            set(handles.StopButton,'BackgroundColor',[0.94 0.94 0.94]);
            set(handles.PlayButton,'Enable','off');
            set(handles.StopButton,'Enable','on');
            set(handles.ResetGainButton,'Enable','on');
            
            if rounds == 1
                freq1 = 125;
                freq2 = 250;
                freq3 = 500;
                freq4 = 1000;
                gainlimit = 15;
                set(handles.roundnumber,'String',rounds);
                set(handles.songnumber,'String',i);
                set(handles.slider1,'Enable','on');
                set(handles.slider2,'Enable','on');
                set(handles.slider3,'Enable','on');
                set(handles.slider4,'Enable','on');
                set(handles.slider5,'Enable','off');
                set(handles.slider6,'Enable','off');
                set(handles.slider7,'Enable','off');
                set(handles.slider8,'Enable','off');
                set(handles.slider9,'Enable','off');
                set(handles.slider10,'Enable','off');
                set(handles.slider11,'Enable','off');
                set(handles.slider12,'Enable','off');
            
                assignin('base','freq1',freq1);
                assignin('base','freq2',freq2);
                assignin('base','freq3',freq3);
                assignin('base','freq4',freq4);
            
                set(handles.text110,'String','125Hz');
                set(handles.text111,'String','250Hz');
                set(handles.text112,'String','500Hz');
                set(handles.text113,'String','1000Hz');
                set(handles.text114,'String','');
                set(handles.text115,'String','');
                set(handles.text116,'String','');
                set(handles.text117,'String','');
                set(handles.text118,'String','');
                set(handles.text119,'String','');
                set(handles.text120,'String','');
                set(handles.text121,'String','');
            
                assignin('base','bw1',100);
                assignin('base','bw2',210);
                assignin('base','bw3',400);
                assignin('base','bw4',700);
            
                set(handles.slider1,'value',0);
                set(handles.slider2,'value',0);
                set(handles.slider3,'value',0);
                set(handles.slider4,'value',0);
            
                gain1 = get(handles.slider1,'value');
                gain2 = get(handles.slider2,'value');
                gain3 = get(handles.slider3,'value');
                gain4 = get(handles.slider4,'value');
            
                assignin('base','gain1',gain1);
                assignin('base','gain2',gain2);
                assignin('base','gain3',gain3);
                assignin('base','gain4',gain4);
            
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4;
                set(handles.text4,'String',gain1);
                set(handles.text5,'String',gain2);
                set(handles.text6,'String',gain3);
                set(handles.text45,'String',gain4);
                set(handles.gainleft,'String',gainleft);
                set(handles.instruction,'String','Song has started. Please only use the first 4 sliders from the left. Click ''Stop'' when finish adjusting.');
                set(handles.text46,'String','');
                set(handles.text59,'String','');
                set(handles.text60,'String','');
                set(handles.text61,'String','');
                set(handles.text62,'String','');
                set(handles.text63,'String','');
                set(handles.text64,'String','');
                set(handles.text65,'String','');
            
                set_param('fifteenbands/Variable Bandwidth IIR Filter5','Commented','on');
                set_param('fifteenbands/Variable Bandwidth IIR Filter6','Commented','on');
                set_param('fifteenbands/Variable Bandwidth IIR Filter7','Commented','on');
                set_param('fifteenbands/Variable Bandwidth IIR Filter8','Commented','on');
                set_param('fifteenbands/Variable Bandwidth IIR Filter9','Commented','on');
                set_param('fifteenbands/Variable Bandwidth IIR Filter10','Commented','on');
                set_param('fifteenbands/Variable Bandwidth IIR Filter11','Commented','on');
                set_param('fifteenbands/Variable Bandwidth IIR Filter12','Commented','on');
                set_param('fifteenbands/dB Gain5','Commented','on');
                set_param('fifteenbands/dB Gain6','Commented','on');
                set_param('fifteenbands/dB Gain7','Commented','on');
                set_param('fifteenbands/dB Gain8','Commented','on');
                set_param('fifteenbands/dB Gain9','Commented','on');
                set_param('fifteenbands/dB Gain10','Commented','on');
                set_param('fifteenbands/dB Gain11','Commented','on');
                set_param('fifteenbands/dB Gain12','Commented','on');
                set_param('fifteenbands/Subtract5','Commented','on');
                set_param('fifteenbands/Subtract6','Commented','on');
                set_param('fifteenbands/Subtract7','Commented','on');
                set_param('fifteenbands/Subtract8','Commented','on');
                set_param('fifteenbands/Subtract9','Commented','on');
                set_param('fifteenbands/Subtract10','Commented','on');
                set_param('fifteenbands/Subtract11','Commented','on');
                set_param('fifteenbands/Subtract12','Commented','on');
                sim('fifteenbands');
            elseif rounds == 2
                set(handles.instruction,'String','Song is repeated. Click ''Stop'' when finish adjusting.');
            
                set(handles.slider1,'value',0);
                set(handles.slider2,'value',0);
                set(handles.slider3,'value',0);
                set(handles.slider4,'value',0);
                set(handles.slider5,'value',0);
                set(handles.slider6,'value',0);
                set(handles.slider7,'value',0);
                set(handles.slider8,'value',0);
                set(handles.slider9,'value',0);
                set(handles.slider10,'value',0);
                set(handles.slider11,'value',0);
                set(handles.slider12,'value',0);
            
                gain1 = get(handles.slider1,'value');
                gain2 = get(handles.slider2,'value');
                gain3 = get(handles.slider3,'value');
                gain4 = get(handles.slider4,'value');
                gain5 = get(handles.slider5,'value');
                gain6 = get(handles.slider6,'value');
                gain7 = get(handles.slider7,'value');
                gain8 = get(handles.slider8,'value');
                gain9 = get(handles.slider9,'value');
                gain10 = get(handles.slider10,'value');
                gain11 = get(handles.slider11,'value');
                gain12 = get(handles.slider12,'value');
            
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                set(handles.gainleft, 'String',gainleft);
                set(handles.text4, 'String',gain1);
                set(handles.text5, 'String',gain2);
                set(handles.text6, 'String',gain3);
                set(handles.text45,'String',gain4);
                set(handles.text46,'String',gain5);
                set(handles.text59,'String',gain6);
                set(handles.text60,'String',gain7);
                set(handles.text61,'String',gain8);
                set(handles.text62,'String',gain9);
                set(handles.text63,'String',gain10);
                set(handles.text64,'String',gain11);
                set(handles.text65,'String',gain12);
            
                assignin('base','gain1',gain1);
                assignin('base','gain2',gain2);
                assignin('base','gain3',gain3);
                assignin('base','gain4',gain4);
                assignin('base','gain5',gain5);
                assignin('base','gain6',gain6);
                assignin('base','gain7',gain7);
                assignin('base','gain8',gain8);
                assignin('base','gain9',gain9);
                assignin('base','gain10',gain10);
                assignin('base','gain11',gain11);
                assignin('base','gain12',gain12);
                sim('fifteenbands');
            end
        end

        % Button pushed function: ResetGainButton
        function ResetGainButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ResetGainButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global gainleft
            global rounds
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            
            set(handles.slider1,'value',0);
            set(handles.slider2,'value',0);
            set(handles.slider3,'value',0);
            set(handles.slider4,'value',0);
            gain1 = get(handles.slider1,'value');
            gain2 = get(handles.slider2,'value');
            gain3 = get(handles.slider3,'value');
            gain4 = get(handles.slider4,'value');
            gainleft = gainlimit + gain1 + gain2 + gain3 + gain4;
            set(handles.gainleft, 'String',gainleft);
            set(handles.text4, 'String',gain1);
            set(handles.text5, 'String',gain2);
            set(handles.text6, 'String',gain3);
            set(handles.text45,'String',gain4);
            assignin('base','gain1',gain1);
            assignin('base','gain2',gain2);
            assignin('base','gain3',gain3);
            assignin('base','gain4',gain4);
            set_param('fifteenbands','SimulationCommand','update');
            
            if rounds == 2
                set(handles.slider1,'value',0);
                set(handles.slider2,'value',0);
                set(handles.slider3,'value',0);
                set(handles.slider4,'value',0);
                set(handles.slider5,'value',0);
                set(handles.slider6,'value',0);
                set(handles.slider7,'value',0);
                set(handles.slider8,'value',0);
                set(handles.slider9,'value',0);
                set(handles.slider10,'value',0);
                set(handles.slider11,'value',0);
                set(handles.slider12,'value',0);
            
                gain1 = get(handles.slider1,'value');
                gain2 = get(handles.slider2,'value');
                gain3 = get(handles.slider3,'value');
                gain4 = get(handles.slider4,'value');
                gain5 = get(handles.slider5,'value');
                gain6 = get(handles.slider6,'value');
                gain7 = get(handles.slider7,'value');
                gain8 = get(handles.slider8,'value');
                gain9 = get(handles.slider9,'value');
                gain10 = get(handles.slider10,'value');
                gain11 = get(handles.slider11,'value');
                gain12 = get(handles.slider12,'value');
            
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                set(handles.gainleft, 'String',gainleft);
                set(handles.text4, 'String',gain1);
                set(handles.text5, 'String',gain2);
                set(handles.text6, 'String',gain3);
                set(handles.text45,'String',gain4);
                set(handles.text46,'String',gain5);
                set(handles.text59,'String',gain6);
                set(handles.text60,'String',gain7);
                set(handles.text61,'String',gain8);
                set(handles.text62,'String',gain9);
                set(handles.text63,'String',gain10);
                set(handles.text64,'String',gain11);
                set(handles.text65,'String',gain12);
            
                assignin('base','gain1',gain1);
                assignin('base','gain2',gain2);
                assignin('base','gain3',gain3);
                assignin('base','gain4',gain4);
                assignin('base','gain5',gain5);
                assignin('base','gain6',gain6);
                assignin('base','gain7',gain7);
                assignin('base','gain8',gain8);
                assignin('base','gain9',gain9);
                assignin('base','gain10',gain10);
                assignin('base','gain11',gain11);
                assignin('base','gain12',gain12);
                set_param('fifteenbands','SimulationCommand','update');
            end
        end

        % Button pushed function: StopButton
        function StopButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to StopButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.StopButton,'BackgroundColor','yellow');
            set(handles.PlayButton,'Enable','on');
            set(handles.SubmitButton,'Enable','on');
            set(handles.ResetGainButton,'Enable','off');
            set(handles.instruction,'String','Song is stopped, click ''Submit'' to submit the result or ''Play to repeat the song''.');
            set_param('fifteenbands','SimulationCommand','stop');
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            set(handles.StopButton,'BackgroundColor',[0.94 0.94 0.94]);
            set(handles.PlayButton,'Enable','off');
            set(handles.ResetGainButton,'Enable','on');
            set(handles.SubmitButton,'Enable','off');
            
            global rounds i songs gainleft gainlimit totalsongs songFolder
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            global freq1 freq2 freq3 freq4 freq5 freq6 freq7 freq8 freq9 freq10 freq11 freq12
            
            totalsongs = 4; %total number of songs include 1st whitenoise and last whitenoise.
            name = get(handles.name,'string');
            
            if rounds == 1
                rounds = rounds + 1;
                assignin('base','rounds',rounds);
                set(handles.roundnumber,'String',rounds);
                [~,B,b] = fileparts(songs{i});
                B = [B b];
                C = {name gain1 gain2 gain3 gain4};
                D = cat(2,{datetime},B,C);
                filename = 'mydata.xlsx';
                writecell(D,filename,'Sheet',1,"WriteMode","append");
                set_param('fifteenbands','SimulationCommand','stop');
            
                set(handles.slider1,'Enable','off');
                set(handles.slider2,'Enable','off');
                set(handles.slider3,'Enable','off');
                set(handles.slider4,'Enable','off');
                set(handles.slider5,'Enable','off');
                set(handles.slider6,'Enable','off');
                set(handles.slider7,'Enable','off');
                set(handles.slider8,'Enable','off');
                set(handles.slider9,'Enable','off');
                set(handles.slider10,'Enable','off');
                set(handles.slider11,'Enable','off');
                set(handles.slider12,'Enable','off');
                set_param('fifteenbands/Variable Bandwidth IIR Filter5','Commented','off');
                set_param('fifteenbands/Variable Bandwidth IIR Filter6','Commented','off');
                set_param('fifteenbands/Variable Bandwidth IIR Filter7','Commented','off');
                set_param('fifteenbands/Variable Bandwidth IIR Filter8','Commented','off');
                set_param('fifteenbands/Variable Bandwidth IIR Filter9','Commented','off');
                set_param('fifteenbands/Variable Bandwidth IIR Filter10','Commented','off');
                set_param('fifteenbands/Variable Bandwidth IIR Filter11','Commented','off');
                set_param('fifteenbands/Variable Bandwidth IIR Filter12','Commented','off');
                set_param('fifteenbands/dB Gain5','Commented','off');
                set_param('fifteenbands/dB Gain6','Commented','off');
                set_param('fifteenbands/dB Gain7','Commented','off');
                set_param('fifteenbands/dB Gain8','Commented','off');
                set_param('fifteenbands/dB Gain9','Commented','off');
                set_param('fifteenbands/dB Gain10','Commented','off');
                set_param('fifteenbands/dB Gain11','Commented','off');
                set_param('fifteenbands/dB Gain12','Commented','off');
                set_param('fifteenbands/Subtract5','Commented','off');
                set_param('fifteenbands/Subtract6','Commented','off');
                set_param('fifteenbands/Subtract7','Commented','off');
                set_param('fifteenbands/Subtract8','Commented','off');
                set_param('fifteenbands/Subtract9','Commented','off') ;
                set_param('fifteenbands/Subtract10','Commented','off');
                set_param('fifteenbands/Subtract11','Commented','off');
                set_param('fifteenbands/Subtract12','Commented','off');
            
                freq1 = 100;
                freq2 = 125;
                freq3 = 160;
                freq4 = 200;
                freq5 = 250;
                freq6 = 315;
                freq7 = 400;
                freq8 = 500;
                freq9 = 630;
                freq10 = 800;
                freq11 = 1000;
                freq12 = 1250;
                gainleft = 15;
            
                assignin('base','freq1',freq1);
                assignin('base','freq2',freq2);
                assignin('base','freq3',freq3);
                assignin('base','freq4',freq4);
                assignin('base','freq5',freq5);
                assignin('base','freq6',freq6);
                assignin('base','freq7',freq7);
                assignin('base','freq8',freq8);
                assignin('base','freq9',freq9);
                assignin('base','freq10',freq10);
                assignin('base','freq11',freq11);
                assignin('base','freq12',freq12);
            
                assignin('base','bw1',10);
                assignin('base','bw2',15);
                assignin('base','bw3',18);
                assignin('base','bw4',25);
                assignin('base','bw5',30);
                assignin('base','bw6',38);
                assignin('base','bw7',45);
                assignin('base','bw8',60);
                assignin('base','bw9',72);
                assignin('base','bw10',91);
                assignin('base','bw11',110);
                assignin('base','bw12',130);
                set(handles.text110,'String','100Hz');
                set(handles.text111,'String','125Hz');
                set(handles.text112,'String','160Hz');
                set(handles.text113,'String','200Hz');
                set(handles.text114,'String','250Hz');
                set(handles.text115,'String','315Hz');
                set(handles.text116,'String','400Hz');
                set(handles.text117,'String','500Hz');
                set(handles.text118,'String','630Hz');
                set(handles.text119,'String','800Hz');
                set(handles.text120,'String','1000Hz');
                set(handles.text121,'String','1250Hz');
                set(handles.text4,'String',0);
                set(handles.text5,'String',0);
                set(handles.text6,'String',0);
                set(handles.text45,'String',0);
                set(handles.text46,'String',0);
                set(handles.text59,'String',0);
                set(handles.text60,'String',0);
                set(handles.text61,'String',0);
                set(handles.text62,'String',0);
                set(handles.text63,'String',0);
                set(handles.text64,'String',0);
                set(handles.text65,'String',0);
                set(handles.gainleft,'String',gainleft);
            
                if ((gain1 < 0) && (gain2 < 0) && (gain3 < 0)  && (gain4 < 0 )) || ((gain1 == gain2) && (gain1 == gain3) && (gain1 == gain4))
                    set(handles.slider1,'Enable','on');
                    set(handles.slider2,'Enable','on');
                    set(handles.slider3,'Enable','on');
                    set(handles.slider1,'value',0);
                    set(handles.slider2,'value',0);
                    set(handles.slider3,'value',0);
                    set(handles.slider4,'Enable','on');
                    set(handles.slider5,'Enable','on');
                    set(handles.slider6,'Enable','on');
                    set(handles.slider4,'value',0);
                    set(handles.slider5,'value',0);
                    set(handles.slider6,'value',0);
                    set(handles.slider7,'Enable','on');
                    set(handles.slider8,'Enable','on');
                    set(handles.slider9,'Enable','on');
                    set(handles.slider7,'value',0);
                    set(handles.slider8,'value',0);
                    set(handles.slider9,'value',0);
                    set(handles.slider10,'Enable','on');
                    set(handles.slider11,'Enable','on');
                    set(handles.slider12,'Enable','on');
                    set(handles.slider10,'value',0);
                    set(handles.slider11,'value',0);
                    set(handles.slider12,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. All sliders are available to adjust.');
                elseif gain1 < 0 && gain2 < 0 && gain3 < 0
                    set(handles.slider1,'Enable','on');
                    set(handles.slider2,'Enable','on');
                    set(handles.slider3,'Enable','on');
                    set(handles.slider1,'value',0);
                    set(handles.slider2,'value',0);
                    set(handles.slider3,'value',0);
                    set(handles.slider4,'Enable','on');
                    set(handles.slider5,'Enable','on');
                    set(handles.slider6,'Enable','on');
                    set(handles.slider4,'value',0);
                    set(handles.slider5,'value',0);
                    set(handles.slider6,'value',0);
                    set(handles.slider7,'Enable','on');
                    set(handles.slider8,'Enable','on');
                    set(handles.slider9,'Enable','on');
                    set(handles.slider7,'value',0);
                    set(handles.slider8,'value',0);
                    set(handles.slider9,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 1,2,3,4,5,6,7,8,9');
                elseif gain1 < 0 && gain2 < 0 && gain4 < 0
                    set(handles.slider1,'Enable','on');
                    set(handles.slider2,'Enable','on');
                    set(handles.slider3,'Enable','on');
                    set(handles.slider1,'value',0);
                    set(handles.slider2,'value',0);
                    set(handles.slider3,'value',0);
                    set(handles.slider4,'Enable','on');
                    set(handles.slider5,'Enable','on');
                    set(handles.slider6,'Enable','on');
                    set(handles.slider4,'value',0);
                    set(handles.slider5,'value',0);
                    set(handles.slider6,'value',0);
                    set(handles.slider10,'Enable','on');
                    set(handles.slider11,'Enable','on');
                    set(handles.slider12,'Enable','on');
                    set(handles.slider10,'value',0);
                    set(handles.slider11,'value',0);
                    set(handles.slider12,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 1,2,3,4,5,6,10,11,12');
                elseif gain1 < 0 && gain3 < 0 && gain4 < 0
                    set(handles.slider1,'Enable','on');
                    set(handles.slider2,'Enable','on');
                    set(handles.slider3,'Enable','on');
                    set(handles.slider1,'value',0);
                    set(handles.slider2,'value',0);
                    set(handles.slider3,'value',0);
                    set(handles.slider7,'Enable','on');
                    set(handles.slider8,'Enable','on');
                    set(handles.slider9,'Enable','on');
                    set(handles.slider7,'value',0);
                    set(handles.slider8,'value',0);
                    set(handles.slider9,'value',0);
                    set(handles.slider10,'Enable','on');
                    set(handles.slider11,'Enable','on');
                    set(handles.slider12,'Enable','on');
                    set(handles.slider10,'value',0);
                    set(handles.slider11,'value',0);
                    set(handles.slider12,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 1,2,3,7,8,9,10,11,12');
                elseif gain2 < 0 && gain3 < 0 && gain4 < 0
                    set(handles.slider4,'Enable','on');
                    set(handles.slider5,'Enable','on');
                    set(handles.slider6,'Enable','on');
                    set(handles.slider4,'value',0);
                    set(handles.slider5,'value',0);
                    set(handles.slider6,'value',0);
                    set(handles.slider7,'Enable','on');
                    set(handles.slider8,'Enable','on');
                    set(handles.slider9,'Enable','on');
                    set(handles.slider7,'value',0);
                    set(handles.slider8,'value',0);
                    set(handles.slider9,'value',0);
                    set(handles.slider10,'Enable','on');
                    set(handles.slider11,'Enable','on');
                    set(handles.slider12,'Enable','on');
                    set(handles.slider10,'value',0);
                    set(handles.slider11,'value',0);
                    set(handles.slider12,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 4,5,6,7,8,9,10,11,12');
                elseif gain1 < 0 && gain2 < 0
                    set(handles.slider1,'Enable','on');
                    set(handles.slider2,'Enable','on');
                    set(handles.slider3,'Enable','on');
                    set(handles.slider1,'value',0);
                    set(handles.slider2,'value',0);
                    set(handles.slider3,'value',0);
                    set(handles.slider4,'Enable','on');
                    set(handles.slider5,'Enable','on');
                    set(handles.slider6,'Enable','on');
                    set(handles.slider4,'value',0);
                    set(handles.slider5,'value',0);
                    set(handles.slider6,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 1,2,3,4,5,6');
                elseif gain1 < 0 && gain3 < 0
                    set(handles.slider1,'Enable','on');
                    set(handles.slider2,'Enable','on');
                    set(handles.slider3,'Enable','on');
                    set(handles.slider1,'value',0);
                    set(handles.slider2,'value',0);
                    set(handles.slider3,'value',0);
                    set(handles.slider7,'Enable','on');
                    set(handles.slider8,'Enable','on');
                    set(handles.slider9,'Enable','on');
                    set(handles.slider7,'value',0);
                    set(handles.slider8,'value',0);
                    set(handles.slider9,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 1,2,3,7,8,9');
                elseif gain1 < 0 && gain4 <0
                    set(handles.slider1,'Enable','on');
                    set(handles.slider2,'Enable','on');
                    set(handles.slider3,'Enable','on');
                    set(handles.slider1,'value',0);
                    set(handles.slider2,'value',0);
                    set(handles.slider3,'value',0);
                    set(handles.slider10,'Enable','on');
                    set(handles.slider11,'Enable','on');
                    set(handles.slider12,'Enable','on');
                    set(handles.slider10,'value',0);
                    set(handles.slider11,'value',0);
                    set(handles.slider12,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 1,2,3,10,11,12');
                elseif gain2 < 0 && gain3 < 0
                    set(handles.slider4,'Enable','on');
                    set(handles.slider5,'Enable','on');
                    set(handles.slider6,'Enable','on');
                    set(handles.slider4,'value',0);
                    set(handles.slider5,'value',0);
                    set(handles.slider6,'value',0);
                    set(handles.slider7,'Enable','on');
                    set(handles.slider8,'Enable','on');
                    set(handles.slider9,'Enable','on');
                    set(handles.slider7,'value',0);
                    set(handles.slider8,'value',0);
                    set(handles.slider9,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 4,5,6,7,8,9');
                elseif gain2 < 0 && gain4 < 0
                    set(handles.slider4,'Enable','on');
                    set(handles.slider5,'Enable','on');
                    set(handles.slider6,'Enable','on');
                    set(handles.slider4,'value',0);
                    set(handles.slider5,'value',0);
                    set(handles.slider6,'value',0);
                    set(handles.slider10,'Enable','on');
                    set(handles.slider11,'Enable','on');
                    set(handles.slider12,'Enable','on');
                    set(handles.slider10,'value',0);
                    set(handles.slider11,'value',0);
                    set(handles.slider12,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 4,5,6,10,11,12');
                elseif gain3 < 0 && gain4 < 0
                    set(handles.slider7,'Enable','on');
                    set(handles.slider8,'Enable','on');
                    set(handles.slider9,'Enable','on');
                    set(handles.slider7,'value',0);
                    set(handles.slider8,'value',0);
                    set(handles.slider9,'value',0);
                    set(handles.slider10,'Enable','on');
                    set(handles.slider11,'Enable','on');
                    set(handles.slider12,'Enable','on');
                    set(handles.slider10,'value',0);
                    set(handles.slider11,'value',0);
                    set(handles.slider12,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 7,8,9,10,11,12');
                elseif gain1 < 0
                    set(handles.slider1,'Enable','on');
                    set(handles.slider2,'Enable','on');
                    set(handles.slider3,'Enable','on');
                    set(handles.slider1,'value',0);
                    set(handles.slider2,'value',0);
                    set(handles.slider3,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 1,2,3');
                elseif gain2 < 0
                    set(handles.slider4,'Enable','on');
                    set(handles.slider5,'Enable','on');
                    set(handles.slider6,'Enable','on');
                    set(handles.slider4,'value',0);
                    set(handles.slider5,'value',0);
                    set(handles.slider6,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 4,5,6');
                elseif gain3 < 0
                    set(handles.slider7,'Enable','on');
                    set(handles.slider8,'Enable','on');
                    set(handles.slider9,'Enable','on');
                    set(handles.slider7,'value',0);
                    set(handles.slider8,'value',0);
                    set(handles.slider9,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 7,8,9');
                elseif gain4 < 0
                    set(handles.slider10,'Enable','on');
                    set(handles.slider11,'Enable','on');
                    set(handles.slider12,'Enable','on');
                    set(handles.slider10,'value',0);
                    set(handles.slider11,'value',0);
                    set(handles.slider12,'value',0);
                    set(handles.instruction,'String','Result of Round 1 has been submitted, please proceed to Round 2. Click ''Stop'' when finish adjusting. Use slider 10,11,12');
                end
                gain1 = 0;
                gain2 = 0;
                gain3 = 0;
                gain4 = 0;
                gain5 = 0;
                gain6 = 0;
                gain7 = 0;
                gain8 = 0;
                gain9 = 0;
                gain10 = 0;
                gain11 = 0;
                gain12 = 0;
                assignin('base','gain1',gain1);
                assignin('base','gain2',gain2);
                assignin('base','gain3',gain3);
                assignin('base','gain4',gain4);
                assignin('base','gain5',gain5);
                assignin('base','gain6',gain6);
                assignin('base','gain7',gain7);
                assignin('base','gain8',gain8);
                assignin('base','gain9',gain9);
                assignin('base','gain10',gain10);
                assignin('base','gain11',gain11);
                assignin('base','gain12',gain12);
                sim('fifteenbands');
            elseif rounds == 2
                gain5 = get(handles.text46,'string');
                gain6 = get(handles.text59,'string');
                gain7 = get(handles.text60,'string');
                gain8 = get(handles.text61,'string');
                gain9 = get(handles.text62,'string');
                gain10 = get(handles.text63,'string');
                gain11 = get(handles.text64,'string');
                gain12 = get(handles.text65,'string');
                rounds = rounds - 1;
                assignin('base','rounds',rounds);
            
                if strcmp(handles.slider1.Enable, 'on') && ...
                        strcmp(handles.slider4.Enable, 'on') && ...
                        strcmp(handles.slider7.Enable, 'on') && ...
                        strcmp(handles.slider10.Enable, 'on')
                    C = {name gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12};
                elseif strcmp(handles.slider1.Enable, 'on') && ...
                        strcmp(handles.slider4.Enable, 'on') && ...
                        strcmp(handles.slider7.Enable, 'on')
                    C = {name gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 "-" "-" "-"};
                elseif strcmp(handles.slider4.Enable, 'on') && ...
                        strcmp(handles.slider7.Enable, 'on') && ...
                        strcmp(handles.slider10.Enable, 'on')
                    C = {name "-" "-" "-" gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12};
                elseif strcmp(handles.slider1.Enable, 'on') && ...
                        strcmp(handles.slider4.Enable, 'on') && ...
                        strcmp(handles.slider10.Enable, 'on')
                    C = {name gain1 gain2 gain3 gain4 gain5 gain6 "-" "-" "-" gain10 gain11 gain12};
                elseif strcmp(handles.slider1.Enable, 'on') && ...
                        strcmp(handles.slider7.Enable, 'on') && ...
                        strcmp(handles.slider10.Enable, 'on')
                    C = {name gain1 gain2 gain3 "-" "-" "-" gain7 gain8 gain9 gain10 gain11 gain12};
                elseif strcmp(handles.slider1.Enable, 'on') && ...
                        strcmp(handles.slider4.Enable, 'on')
                    C = {name gain1 gain2 gain3 gain4 gain5 gain6 "-" "-" "-" "-" "-" "-"};
                elseif strcmp(handles.slider1.Enable, 'on') && ...
                        strcmp(handles.slider7.Enable, 'on')
                    C = {name gain1 gain2 gain3 "-" "-" "-" gain7 gain8 gain9 "-" "-" "-"};
                elseif strcmp(handles.slider1.Enable, 'on') && ...
                        strcmp(handles.slider10.Enable, 'on')
                    C = {name gain1 gain2 gain3 "-" "-" "-" "-" "-" "-" gain10 gain11 gain12};
                elseif strcmp(handles.slider4.Enable, 'on') && ...
                        strcmp(handles.slider7.Enable, 'on')
                    C = {name "-" "-" "-" gain4 gain5 gain6 gain7 gain8 gain9 "-" "-" "-"};
                elseif strcmp(handles.slider4.Enable, 'on') && ...
                        strcmp(handles.slider10.Enable, 'on')
                    C = {name "-" "-" "-" gain4 gain5 gain6 "-" "-" "-" gain10 gain11 gain12};
                elseif strcmp(handles.slider7.Enable, 'on') && ...
                        strcmp(handles.slider10.Enable, 'on')
                    C = {name "-" "-" "-" "-" "-" "-" gain7 gain8 gain9 gain10 gain11 gain12};
                elseif strcmp(handles.slider1.Enable, 'on')
                    C = {name gain1 gain2 gain3 "-" "-" "-" "-" "-" "-" "-" "-" "-"};
                elseif strcmp(handles.slider4.Enable, 'on')
                    C = {name "-" "-" "-" gain4 gain5 gain6 "-" "-" "-" "-" "-" "-"};
                elseif strcmp(handles.slider7.Enable, 'on')
                    C = {name "-" "-" "-" "-" "-" "-" gain7 gain8 gain9 "-" "-" "-"};
                elseif strcmp(handles.slider10.Enable, 'on')
                    C = {name "-" "-" "-" "-" "-" "-" "-" "-" "-" gain10 gain11 gain12};
                end
                [~,B,b] = fileparts(songs{i});
                B = [B b];
                D = cat(2,{datetime},B,C);
                filename = 'mydata.xlsx';
                writecell(D,filename,'Sheet',2,"WriteMode","append");
            
                if i == totalsongs
                    set(handles.instruction,'String','You have completed the experiment, thank you.');
                    msgbox("The experiment has been completed","Success");
                    set(handles.PlayButton,'Enable','off');
                    set(handles.Initialize,'Enable','off');
                    set(handles.ResetGainButton,'Enable','off');
                    set(handles.StopButton,'Enable','off');
                    set(handles.SubmitButton,'Enable','off');
                elseif i <= totalsongs
                    set(handles.instruction,'String','Result of Round 2 has been submitted. Please proceed to Round 1 of the next song and only use the first 4 sliders from the left. Click ''Stop'' when finish adjusting.');
                    i = i + 1;
                    set_param('fifteenbands/From Multimedia File','Filename',songs{i});
                    %playing the next song
                    rounds = 1;
                    assignin('base','rounds',rounds);
                    freq1 = 125;
                    freq2 = 250;
                    freq3 = 500;
                    freq4 = 1000;
                    gainlimit = 15;
                    set(handles.roundnumber,'String',rounds);
                    set(handles.songnumber,'String',i);
                    set(handles.slider1,'Enable','on');
                    set(handles.slider2,'Enable','on');
                    set(handles.slider3,'Enable','on');
                    set(handles.slider4,'Enable','on');
                    set(handles.slider5,'Enable','off');
                    set(handles.slider6,'Enable','off');
                    set(handles.slider7,'Enable','off');
                    set(handles.slider8,'Enable','off');
                    set(handles.slider9,'Enable','off');
                    set(handles.slider10,'Enable','off');
                    set(handles.slider11,'Enable','off');
                    set(handles.slider12,'Enable','off');
            
                    assignin('base','freq1',freq1);
                    assignin('base','freq2',freq2);
                    assignin('base','freq3',freq3);
                    assignin('base','freq4',freq4);
            
                    set(handles.text110,'String','125Hz');
                    set(handles.text111,'String','250Hz');
                    set(handles.text112,'String','500Hz');
                    set(handles.text113,'String','1000Hz');
                    set(handles.text114,'String','');
                    set(handles.text115,'String','');
                    set(handles.text116,'String','');
                    set(handles.text117,'String','');
                    set(handles.text118,'String','');
                    set(handles.text119,'String','');
                    set(handles.text120,'String','');
                    set(handles.text121,'String','');
            
                    assignin('base','bw1',100);
                    assignin('base','bw2',210);
                    assignin('base','bw3',400);
                    assignin('base','bw4',700);
            
                    set(handles.slider1,'value',0);
                    set(handles.slider2,'value',0);
                    set(handles.slider3,'value',0);
                    set(handles.slider4,'value',0);
            
                    gain1 = get(handles.slider1,'value');
                    gain2 = get(handles.slider2,'value');
                    gain3 = get(handles.slider3,'value');
                    gain4 = get(handles.slider4,'value');
            
                    assignin('base','gain1',gain1);
                    assignin('base','gain2',gain2);
                    assignin('base','gain3',gain3);
                    assignin('base','gain4',gain4);
            
                    gainleft = gainlimit + gain1 + gain2 + gain3 + gain4;
                    set(handles.text4,'String',gain1);
                    set(handles.text5,'String',gain2);
                    set(handles.text6,'String',gain3);
                    set(handles.text45,'String',gain4);
                    set(handles.gainleft,'String',gainleft);
                    set(handles.text46,'String','');
                    set(handles.text59,'String','');
                    set(handles.text60,'String','');
                    set(handles.text61,'String','');
                    set(handles.text62,'String','');
                    set(handles.text63,'String','');
                    set(handles.text64,'String','');
                    set(handles.text65,'String','');
            
                    set_param('fifteenbands/Variable Bandwidth IIR Filter5','Commented','on');
                    set_param('fifteenbands/Variable Bandwidth IIR Filter6','Commented','on');
                    set_param('fifteenbands/Variable Bandwidth IIR Filter7','Commented','on');
                    set_param('fifteenbands/Variable Bandwidth IIR Filter8','Commented','on');
                    set_param('fifteenbands/Variable Bandwidth IIR Filter9','Commented','on');
                    set_param('fifteenbands/Variable Bandwidth IIR Filter10','Commented','on');
                    set_param('fifteenbands/Variable Bandwidth IIR Filter11','Commented','on');
                    set_param('fifteenbands/Variable Bandwidth IIR Filter12','Commented','on');
                    set_param('fifteenbands/dB Gain5','Commented','on');
                    set_param('fifteenbands/dB Gain6','Commented','on');
                    set_param('fifteenbands/dB Gain7','Commented','on');
                    set_param('fifteenbands/dB Gain8','Commented','on');
                    set_param('fifteenbands/dB Gain9','Commented','on');
                    set_param('fifteenbands/dB Gain10','Commented','on');
                    set_param('fifteenbands/dB Gain11','Commented','on');
                    set_param('fifteenbands/dB Gain12','Commented','on');
                    set_param('fifteenbands/Subtract5','Commented','on');
                    set_param('fifteenbands/Subtract6','Commented','on');
                    set_param('fifteenbands/Subtract7','Commented','on');
                    set_param('fifteenbands/Subtract8','Commented','on');
                    set_param('fifteenbands/Subtract9','Commented','on');
                    set_param('fifteenbands/Subtract10','Commented','on');
                    set_param('fifteenbands/Subtract11','Commented','on');
                    set_param('fifteenbands/Subtract12','Commented','on');
                    sim('fifteenbands');
                end
            end
        end

        % Value changed function: slider10
        function slider10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain10 = round(get(handles.slider10,'value'));
            
            gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
            if gainleft > gainlimit
                assignin('base','gain10',gain10);
                set(handles.gainleft,'String',gainlimit);
                set(handles.text63,'String',gain10);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft <= gainlimit && gainleft >= 0
                assignin('base','gain10',gain10);
                set(handles.gainleft,'String',gainleft);
                set(handles.text63,'String',gain10);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft < 0
                if gain10 >= 0
                    gain10 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain11 + gain12;
                    gain10 = -gain10;
                    assignin('base','gain10',gain10);
                    set(handles.text63,'String',gain10);
                    set(handles.slider10,'value',gain10);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gain10 < 0
                    gain10 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain11 + gain12;
                    gain10 = -gain10;
                    assignin('base','gain10',gain10);
                    set(handles.text63,'String',gain10);
                    set(handles.slider10,'value',gain10);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                end
            end
        end

        % Value changed function: slider11
        function slider11_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain11 = round(get(handles.slider11,'value'));
            
            gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
            if gainleft > 15
                assignin('base','gain11',gain11);
                set(handles.gainleft,'String',gainlimit);
                set(handles.text64,'String',gain11);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft <= gainlimit && gainleft >= 0
                assignin('base','gain11',gain11);
                set(handles.gainleft,'String',gainleft);
                set(handles.text64,'String',gain11);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft < 0
                if gain11 >= 0
                    gain11 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain12;
                    gain11 = -gain11;
                    assignin('base','gain11',gain11);
                    set(handles.text64,'String',gain11);
                    set(handles.slider11,'value',gain11);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gain11 < 0
                    gain11 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain12;
                    gain11 = -gain11;
                    assignin('base','gain11',gain11);
                    set(handles.text64,'String',gain11);
                    set(handles.slider11,'value',gain11);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                end
            end
        end

        % Value changed function: slider12
        function slider12_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain12 = round(get(handles.slider12,'value'));
            
            gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
            if gainleft > gainlimit
                assignin('base','gain12',gain12);
                set(handles.gainleft,'String',gainlimit);
                set(handles.text65,'String',gain12);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft <= gainlimit && gainleft >= 0
                assignin('base','gain12',gain12);
                set(handles.gainleft,'String',gainleft);
                set(handles.text65,'String',gain12);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft < 0
                if gain12 >= 0
                    gain12 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11;
                    gain12 = -gain12;
                    assignin('base','gain12',gain12);
                    set(handles.text65,'String',gain12);
                    set(handles.slider12,'value',gain12);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gain12 < 0
                    gain12 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11;
                    gain12 = -gain12;
                    assignin('base','gain12',gain12);
                    set(handles.text65,'String',gain12);
                    set(handles.slider12,'value',gain12);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                end
            end
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global gainleft
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            global rounds
            global gainlimit
            gain1 = round(get(handles.slider1,'value'));
            
            if rounds == 1
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4;
                assignin('base','gainleft',gainleft);
                if gainleft > gainlimit
                    assignin('base','gain1',gain1);
                    set(handles.gainleft, 'String',gainlimit);
                    set(handles.text4, 'String',gain1);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft <= gainlimit && gainleft >= 0
                    assignin('base','gain1',gain1);
                    set(handles.gainleft, 'String',gainleft);
                    set(handles.text4, 'String',gain1);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft < 0
                    if gain1 >= 0
                        gain1 = gainlimit + gain2 + gain3 + gain4;
                        gain1 = -gain1;
                        assignin('base','gain1',gain1);
                        set(handles.text4, 'String',gain1);
                        set(handles.slider1,'value',gain1);
                        set(handles.gainleft, 'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    elseif gain1 < 0
                        gain1 = gainlimit + gain2 + gain3 + gain4;
                        gain1 = -gain1;
                        assignin('base','gain1',gain1);
                        set(handles.text4, 'String',gain1);
                        set(handles.slider1,'value',gain1);
                        set(handles.gainleft, 'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    end
                end
            elseif rounds > 1
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                if gainleft > gainlimit
                    assignin('base','gain1',gain1);
                    set(handles.gainleft, 'String',gainlimit);
                    set(handles.text4, 'String',gain1);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft <= gainlimit && gainleft >= 0
                    assignin('base','gain1',gain1);
                    set(handles.gainleft, 'String',gainleft);
                    set(handles.text4, 'String',gain1);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft < 0
                    if gain1 >= 0
                        gain1 = gainlimit + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                        gain1 = -gain1;
                        assignin('base','gain1',gain1);
                        set(handles.text4, 'String',gain1);
                        set(handles.slider1,'value',gain1);
                        set(handles.gainleft, 'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    elseif gain1 < 0
                        gain1 = gainlimit + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                        gain1 = -gain1;
                        assignin('base','gain1',gain1);
                        set(handles.text4, 'String',gain1);
                        set(handles.slider1,'value',gain1);
                        set(handles.gainleft, 'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    end
                end
            end
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global gainleft
            global rounds
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain2 = round(get(handles.slider2,'value'));
            
            if rounds == 1
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4;
                if gainleft > gainlimit
                    assignin('base','gain2',gain2);
                    set(handles.text5, 'String',gain2);
                    set(handles.gainleft, 'String',gainlimit);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft <= gainlimit && gainleft >= 0
                    assignin('base','gain2',gain2);
                    set(handles.gainleft,'String',gainleft);
                    set(handles.text5,'String',gain2);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft < 0
                    if gain2 >= 0
                        gain2 = gainlimit + gain1 + gain3 + gain4;
                        gain2 = -gain2;
                        assignin('base','gain2',gain2);
                        set(handles.text5, 'String',gain2);
                        set(handles.slider2,'value',gain2);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    elseif gain2 < 0
                        gain2 = gainlimit + gain1 + gain3 + gain4;
                        gain2 = -gain2;
                        assignin('base','gain2',gain2);
                        set(handles.text5,'String',gain2);
                        set(handles.slider2,'value',gain2);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    end
                end
            elseif rounds > 1
            
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                if gainleft > gainlimit
                    assignin('base','gain2',gain2);
                    set(handles.gainleft,'String',gainlimit);
                    set(handles.text5,'String',gain2);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft <= gainlimit && gainleft >= 0
                    assignin('base','gain2',gain2);
                    set(handles.gainleft,'String',gainleft);
                    set(handles.text5,'String',gain2);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft < 0
                    if gain2 >= 0
                        gain2 = gainlimit + gain1 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                        gain2 = -gain2;
                        assignin('base','gain2',gain2);
                        set(handles.text5,'String',gain2);
                        set(handles.slider2,'value',gain2);
                        set(handles.gainleft, 'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    elseif gain2 < 0
                        gain2 = gainlimit + gain1 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                        gain2 = -gain2;
                        assignin('base','gain2',gain2);
                        set(handles.text5,'String',gain2);
                        set(handles.slider2,'value',gain2);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    end
                end
            end
        end

        % Value changed function: slider3
        function slider3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global rounds
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain3 = round(get(handles.slider3,'value'));
            
            if rounds == 1
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4;
                if gainleft > gainlimit
                    assignin('base','gain3',gain3);
                    set(handles.text6,'String',gain3);
                    set(handles.gainleft,'String',gainlimit);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft <= gainlimit && gainleft >= 0
                    assignin('base','gain3',gain3);
                    set(handles.gainleft,'String',gainleft);
                    set(handles.text6,'String',gain3);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft < 0
                    if gain3 >= 0
                        gain3 = gainlimit + gain1 + gain2 + gain4;
                        gain3 = -gain3;
                        assignin('base','gain3',gain3);
                        set(handles.text6,'String',gain3);
                        set(handles.slider3,'value',gain3);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    elseif gain3 < 0
                        gain3 = gainlimit + gain1 + gain2 + gain4;
                        gain3 = -gain3;
                        assignin('base','gain3',gain3);
                        set(handles.text6,'String',gain3);
                        set(handles.slider3,'value',gain3);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    end
                end
            elseif rounds > 1
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                if gainleft > gainlimit
                    assignin('base','gain3',gain3);
                    set(handles.gainleft,'String',gainlimit);
                    set(handles.text6,'String',gain3);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft <= gainlimit && gainleft >= 0
                    assignin('base','gain3',gain3);
                    set(handles.gainleft,'String',gainleft);
                    set(handles.text6,'String',gain3);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft < 0
                    if gain3 >= 0
                        gain3 = gainlimit + gain1 + gain2 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                        gain3 = -gain3;
                        assignin('base','gain3',gain3);
                        set(handles.text6,'String',gain3);
                        set(handles.slider3,'value',gain2);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    elseif gain3 < 0
                        gain3 = gainlimit + gain1 + gain2 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                        gain3 = -gain3;
                        assignin('base','gain3',gain3);
                        set(handles.text6,'String',gain3);
                        set(handles.slider3,'value',gain3);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    end
                end
            end
        end

        % Value changed function: slider4
        function slider4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global rounds
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain4 = round(get(handles.slider4,'value'));
            
            if rounds == 1
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4;
                if gainleft > gainlimit
                    assignin('base','gain4',gain4);
                    set(handles.text45,'String',gain4);
                    set(handles.gainleft,'String',gainlimit);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft <= gainlimit && gainleft >= 0
                    assignin('base','gain4',gain4);
                    set(handles.gainleft,'String',gainleft);
                    set(handles.text45,'String',gain4);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft < 0
                    if gain4 >= 0
                        gain4 = gainlimit + gain1 + gain2 + gain3;
                        gain4 = -gain4;
                        assignin('base','gain4',gain4);
                        set(handles.text45,'String',gain4);
                        set(handles.slider4,'value',gain4);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    elseif gain4 < 0
                        gain4 = gainlimit + gain1 + gain2 + gain3;
                        gain4 = -gain4;
                        assignin('base','gain4',gain4);
                        set(handles.text45,'String',gain4);
                        set(handles.slider4,'value',gain4);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    end
                end
            elseif rounds > 1
            
                gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                if gainleft > 15
                    assignin('base','gain4',gain4);
                    set(handles.gainleft,'String',gainlimit);
                    set(handles.text45,'String',gain4);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft <= gainlimit && gainleft >= 0
                    assignin('base','gain4',gain4);
                    set(handles.gainleft,'String',gainleft);
                    set(handles.text45,'String',gain4);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gainleft < 0
                    if gain4 >= 0
                        gain4 = gainlimit + gain1 + gain2 + gain3 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                        gain4 = -gain4;
                        assignin('base','gain4',gain34);
                        set(handles.text45,'String',gain4);
                        set(handles.slider4,'value',gain4);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    elseif gain4 < 0
                        gain4 = gainlimit + gain1 + gain2 + gain3 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                        gain4 = -gain4;
                        assignin('base','gain4',gain4);
                        set(handles.text45,'String',gain4);
                        set(handles.slider4,'value',gain4);
                        set(handles.gainleft,'String',0);
                        set_param('fifteenbands','SimulationCommand','update');
                    end
                end
            end
        end

        % Value changed function: slider5
        function slider5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain5 = round(get(handles.slider5,'value'));
            
            gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
            if gainleft > gainlimit
                assignin('base','gain5',gain5);
                set(handles.gainleft,'String',gainlimit);
                set(handles.text46,'String',gain5);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft <= gainlimit && gainleft >= 0
                assignin('base','gain5',gain5);
                set(handles.gainleft,'String',gainleft);
                set(handles.text46,'String',gain5);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft < 0
                if gain5 >= 0
                    gain5 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                    gain5 = -gain5;
                    assignin('base','gain5',gain5);
                    set(handles.text46,'String',gain5);
                    set(handles.slider5,'value',gain5);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gain5 < 0
                    gain5 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                    gain5 = -gain5;
                    assignin('base','gain5',gain5);
                    set(handles.text46,'String',gain5);
                    set(handles.slider5,'value',gain5);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                end
            end
        end

        % Value changed function: slider6
        function slider6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain6 = round(get(handles.slider6,'value'));
            
            gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
            if gainleft > gainlimit
                assignin('base','gain6',gain6);
                set(handles.gainleft,'String',gainlimit);
                set(handles.text59,'String',gain6);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft <= gainlimit && gainleft >= 0
                assignin('base','gain6',gain6);
                set(handles.gainleft,'String',gainleft);
                set(handles.text59,'String',gain6);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft < 0
                if gain6 >= 0
                    gain6 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                    gain6 = -gain6;
                    assignin('base','gain6',gain6);
                    set(handles.text59,'String',gain6);
                    set(handles.slider6,'value',gain6);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gain6 < 0
                    gain6 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
                    gain6 = -gain6;
                    assignin('base','gain6',gain6);
                    set(handles.text59,'String',gain6);
                    set(handles.slider6,'value',gain6);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                end
            end
        end

        % Value changed function: slider7
        function slider7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain7 = round(get(handles.slider7,'value'));
            
            gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
            if gainleft > gainlimit
                assignin('base','gain7',gain7);
                set(handles.gainleft,'String',gainlimit);
                set(handles.text60,'String',gain7);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft <= gainlimit && gainleft >= 0
                assignin('base','gain7',gain7);
                set(handles.gainleft,'String',gainleft);
                set(handles.text60,'String',gain7);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft < 0
                if gain7 >= 0
                    gain7 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain8 + gain9 + gain10 + gain11 + gain12;
                    gain7 = -gain7;
                    assignin('base','gain7',gain7);
                    set(handles.text60,'String',gain7);
                    set(handles.slider7,'value',gain7);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gain7 < 0
                    gain7 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain8 + gain9 + gain10 + gain11 + gain12;
                    gain7 = -gain7;
                    assignin('base','gain7',gain7);
                    set(handles.text60,'String',gain7);
                    set(handles.slider7,'value',gain7);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                end
            end
        end

        % Value changed function: slider8
        function slider8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain8 = round(get(handles.slider8,'value'));
            
            gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
            if gainleft > gainlimit
                assignin('base','gain8',gain8);
                set(handles.gainleft,'String',gainlimit);
                set(handles.text61,'String',gain8);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft <= gainlimit && gainleft >= 0
                assignin('base','gain8',gain8);
                set(handles.gainleft,'String',gainleft);
                set(handles.text61,'String',gain8);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft < 0
                if gain8 >= 0
                    gain8 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain9 + gain10 + gain11 + gain12;
                    gain8 = -gain8;
                    assignin('base','gain8',gain8);
                    set(handles.text61,'String',gain8);
                    set(handles.slider8,'value',gain8);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gain8 < 0
                    gain8 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain9 + gain10 + gain11 + gain12;
                    gain8 = -gain8;
                    assignin('base','gain8',gain8);
                    set(handles.text61,'String',gain8);
                    set(handles.slider8,'value',gain8);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                end
            end
        end

        % Value changed function: slider9
        function slider9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            global gainleft
            global gainlimit
            global gain1 gain2 gain3 gain4 gain5 gain6 gain7 gain8 gain9 gain10 gain11 gain12
            gain9 = round(get(handles.slider9,'value'));
            
            gainleft = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain9 + gain10 + gain11 + gain12;
            if gainleft > gainlimit
                assignin('base','gain9',gain9);
                set(handles.gainleft,'String',gainlimit);
                set(handles.text62,'String',gain9);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft <= gainlimit && gainleft >= 0
                assignin('base','gain9',gain9);
                set(handles.gainleft,'String',gainleft);
                set(handles.text62,'String',gain9);
                set_param('fifteenbands','SimulationCommand','update');
            elseif gainleft < 0
                if gain9 >= 0
                    gain9 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain10 + gain11 + gain12;
                    gain9 = -gain9;
                    assignin('base','gain9',gain9);
                    set(handles.text62,'String',gain9);
                    set(handles.slider9,'value',gain9);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                elseif gain9 < 0
                    gain9 = gainlimit + gain1 + gain2 + gain3 + gain4 + gain5 + gain6 + gain7 + gain8 + gain10 + gain11 + gain12;
                    gain9 = -gain9;
                    assignin('base','gain9',gain9);
                    set(handles.text62,'String',gain9);
                    set(handles.slider9,'value',gain9);
                    set(handles.gainleft,'String',0);
                    set_param('fifteenbands','SimulationCommand','update');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Equalizer';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Graphic Audio Equalizer';
            app.uipanel1.Tag = 'uipanel1';
            app.PlayButton = uibutton(app.uipanel1, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_Callback, true);
            app.PlayButton.Tag = 'PlayButton';
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.slider2 = uislider(app.uipanel1);
            app.slider2.Limits = [-15 15];
            app.slider2.MajorTicks = [];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.slider2.Tag = 'slider2';
            app.slider2.Enable = 'off';
            app.slider1 = uislider(app.uipanel1);
            app.slider1.Limits = [-15 15];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider1.Enable = 'off';
            app.ResetGainButton = uibutton(app.uipanel1, 'push');
            app.ResetGainButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGainButton_Callback, true);
            app.ResetGainButton.Tag = 'ResetGainButton';
            app.ResetGainButton.Enable = 'off';
            app.ResetGainButton.Text = 'Reset Gain ';
            app.StopButton = uibutton(app.uipanel1, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Callback, true);
            app.StopButton.Tag = 'StopButton';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = '';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = '';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = '';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Number of gain changes left:';
            app.gainleft = uilabel(app.uipanel1);
            app.gainleft.Tag = 'gainleft';
            app.gainleft.Text = '';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = '0';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = '0';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = '0';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = '-15';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = '-15';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = '-15';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = '+15';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = '+15';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = '+15';
            app.text28 = uilabel(app.uipanel1);
            app.text28.Tag = 'text28';
            app.text28.Text = 'dB';
            app.text29 = uilabel(app.uipanel1);
            app.text29.Tag = 'text29';
            app.text29.Text = 'dB';
            app.text30 = uilabel(app.uipanel1);
            app.text30.Tag = 'text30';
            app.text30.Text = 'dB';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'SubmitButton';
            app.SubmitButton.Enable = 'off';
            app.SubmitButton.Text = 'Submit';
            app.CloseButton = uibutton(app.uipanel1, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_Callback, true);
            app.CloseButton.Tag = 'CloseButton';
            app.CloseButton.Text = 'Close';
            app.Initialize = uibutton(app.uipanel1, 'push');
            app.Initialize.ButtonPushedFcn = createCallbackFcn(app, @Initialize_Callback, true);
            app.Initialize.Tag = 'Initialize';
            app.Initialize.Text = 'Initialize';
            app.slider4 = uislider(app.uipanel1);
            app.slider4.Limits = [-15 15];
            app.slider4.MajorTicks = [];
            app.slider4.ValueChangedFcn = createCallbackFcn(app, @slider4_Callback, true);
            app.slider4.MinorTicks = [];
            app.slider4.Tag = 'slider4';
            app.slider4.Enable = 'off';
            app.slider5 = uislider(app.uipanel1);
            app.slider5.Limits = [-15 15];
            app.slider5.MajorTicks = [];
            app.slider5.ValueChangedFcn = createCallbackFcn(app, @slider5_Callback, true);
            app.slider5.MinorTicks = [];
            app.slider5.Tag = 'slider5';
            app.slider5.Enable = 'off';
            app.text38 = uilabel(app.uipanel1);
            app.text38.Tag = 'text38';
            app.text38.Text = '+15';
            app.text40 = uilabel(app.uipanel1);
            app.text40.Tag = 'text40';
            app.text40.Text = '+15';
            app.text41 = uilabel(app.uipanel1);
            app.text41.Tag = 'text41';
            app.text41.Text = '0';
            app.text42 = uilabel(app.uipanel1);
            app.text42.Tag = 'text42';
            app.text42.Text = '0';
            app.text43 = uilabel(app.uipanel1);
            app.text43.Tag = 'text43';
            app.text43.Text = '-15';
            app.text44 = uilabel(app.uipanel1);
            app.text44.Tag = 'text44';
            app.text44.Text = '-15';
            app.text45 = uilabel(app.uipanel1);
            app.text45.Tag = 'text45';
            app.text45.Text = '';
            app.text46 = uilabel(app.uipanel1);
            app.text46.Tag = 'text46';
            app.text46.Text = '';
            app.text47 = uilabel(app.uipanel1);
            app.text47.Tag = 'text47';
            app.text47.Text = 'dB';
            app.text48 = uilabel(app.uipanel1);
            app.text48.Tag = 'text48';
            app.text48.Text = 'dB';
            app.slider6 = uislider(app.uipanel1);
            app.slider6.Limits = [-15 15];
            app.slider6.MajorTicks = [];
            app.slider6.ValueChangedFcn = createCallbackFcn(app, @slider6_Callback, true);
            app.slider6.MinorTicks = [];
            app.slider6.Tag = 'slider6';
            app.slider6.Enable = 'off';
            app.slider7 = uislider(app.uipanel1);
            app.slider7.Limits = [-15 15];
            app.slider7.MajorTicks = [];
            app.slider7.ValueChangedFcn = createCallbackFcn(app, @slider7_Callback, true);
            app.slider7.MinorTicks = [];
            app.slider7.Tag = 'slider7';
            app.slider7.Enable = 'off';
            app.slider8 = uislider(app.uipanel1);
            app.slider8.Limits = [-15 15];
            app.slider8.MajorTicks = [];
            app.slider8.ValueChangedFcn = createCallbackFcn(app, @slider8_Callback, true);
            app.slider8.MinorTicks = [];
            app.slider8.Tag = 'slider8';
            app.slider8.Enable = 'off';
            app.slider9 = uislider(app.uipanel1);
            app.slider9.Limits = [-15 15];
            app.slider9.MajorTicks = [];
            app.slider9.ValueChangedFcn = createCallbackFcn(app, @slider9_Callback, true);
            app.slider9.MinorTicks = [];
            app.slider9.Tag = 'slider9';
            app.slider9.Enable = 'off';
            app.slider10 = uislider(app.uipanel1);
            app.slider10.Limits = [-15 15];
            app.slider10.MajorTicks = [];
            app.slider10.ValueChangedFcn = createCallbackFcn(app, @slider10_Callback, true);
            app.slider10.MinorTicks = [];
            app.slider10.Tag = 'slider10';
            app.slider10.Enable = 'off';
            app.slider11 = uislider(app.uipanel1);
            app.slider11.Limits = [-15 15];
            app.slider11.MajorTicks = [];
            app.slider11.ValueChangedFcn = createCallbackFcn(app, @slider11_Callback, true);
            app.slider11.MinorTicks = [];
            app.slider11.Tag = 'slider11';
            app.slider11.Enable = 'off';
            app.slider12 = uislider(app.uipanel1);
            app.slider12.Limits = [-15 15];
            app.slider12.MajorTicks = [];
            app.slider12.ValueChangedFcn = createCallbackFcn(app, @slider12_Callback, true);
            app.slider12.MinorTicks = [];
            app.slider12.Tag = 'slider12';
            app.slider12.Enable = 'off';
            app.text59 = uilabel(app.uipanel1);
            app.text59.Tag = 'text59';
            app.text59.Text = '';
            app.text60 = uilabel(app.uipanel1);
            app.text60.Tag = 'text60';
            app.text60.Text = '';
            app.text61 = uilabel(app.uipanel1);
            app.text61.Tag = 'text61';
            app.text61.Text = '';
            app.text62 = uilabel(app.uipanel1);
            app.text62.Tag = 'text62';
            app.text62.Text = '';
            app.text63 = uilabel(app.uipanel1);
            app.text63.Tag = 'text63';
            app.text63.Text = '';
            app.text64 = uilabel(app.uipanel1);
            app.text64.Tag = 'text64';
            app.text64.Text = '';
            app.text65 = uilabel(app.uipanel1);
            app.text65.Tag = 'text65';
            app.text65.Text = '';
            app.text69 = uilabel(app.uipanel1);
            app.text69.Tag = 'text69';
            app.text69.Text = 'dB';
            app.text70 = uilabel(app.uipanel1);
            app.text70.Tag = 'text70';
            app.text70.Text = 'dB';
            app.text71 = uilabel(app.uipanel1);
            app.text71.Tag = 'text71';
            app.text71.Text = 'dB';
            app.text72 = uilabel(app.uipanel1);
            app.text72.Tag = 'text72';
            app.text72.Text = 'dB';
            app.text73 = uilabel(app.uipanel1);
            app.text73.Tag = 'text73';
            app.text73.Text = 'dB';
            app.text74 = uilabel(app.uipanel1);
            app.text74.Tag = 'text74';
            app.text74.Text = 'dB';
            app.text75 = uilabel(app.uipanel1);
            app.text75.Tag = 'text75';
            app.text75.Text = 'dB';
            app.text79 = uilabel(app.uipanel1);
            app.text79.Tag = 'text79';
            app.text79.Text = '+15';
            app.text80 = uilabel(app.uipanel1);
            app.text80.Tag = 'text80';
            app.text80.Text = '+15';
            app.text81 = uilabel(app.uipanel1);
            app.text81.Tag = 'text81';
            app.text81.Text = '+15';
            app.text82 = uilabel(app.uipanel1);
            app.text82.Tag = 'text82';
            app.text82.Text = '+15';
            app.text83 = uilabel(app.uipanel1);
            app.text83.Tag = 'text83';
            app.text83.Text = '+15';
            app.text84 = uilabel(app.uipanel1);
            app.text84.Tag = 'text84';
            app.text84.Text = '+15';
            app.text85 = uilabel(app.uipanel1);
            app.text85.Tag = 'text85';
            app.text85.Text = '+15';
            app.text89 = uilabel(app.uipanel1);
            app.text89.Tag = 'text89';
            app.text89.Text = '0';
            app.text90 = uilabel(app.uipanel1);
            app.text90.Tag = 'text90';
            app.text90.Text = '0';
            app.text91 = uilabel(app.uipanel1);
            app.text91.Tag = 'text91';
            app.text91.Text = '0';
            app.text92 = uilabel(app.uipanel1);
            app.text92.Tag = 'text92';
            app.text92.Text = '0';
            app.text93 = uilabel(app.uipanel1);
            app.text93.Tag = 'text93';
            app.text93.Text = '0';
            app.text94 = uilabel(app.uipanel1);
            app.text94.Tag = 'text94';
            app.text94.Text = '0';
            app.text95 = uilabel(app.uipanel1);
            app.text95.Tag = 'text95';
            app.text95.Text = '0';
            app.text99 = uilabel(app.uipanel1);
            app.text99.Tag = 'text99';
            app.text99.Text = '-15';
            app.text100 = uilabel(app.uipanel1);
            app.text100.Tag = 'text100';
            app.text100.Text = '-15';
            app.text101 = uilabel(app.uipanel1);
            app.text101.Tag = 'text101';
            app.text101.Text = '-15';
            app.text102 = uilabel(app.uipanel1);
            app.text102.Tag = 'text102';
            app.text102.Text = '-15';
            app.text103 = uilabel(app.uipanel1);
            app.text103.Tag = 'text103';
            app.text103.Text = '-15';
            app.text104 = uilabel(app.uipanel1);
            app.text104.Tag = 'text104';
            app.text104.Text = '-15';
            app.text105 = uilabel(app.uipanel1);
            app.text105.Tag = 'text105';
            app.text105.Text = '-15';
            app.instruction = uilabel(app.uipanel1);
            app.instruction.Tag = 'instruction';
            app.instruction.Text = 'Begin by clicking ''Initialize''. Enter your Matriculation number in the text box at the top left corner. ';
            app.text110 = uilabel(app.uipanel1);
            app.text110.Tag = 'text110';
            app.text110.Text = '';
            app.text111 = uilabel(app.uipanel1);
            app.text111.Tag = 'text111';
            app.text111.Text = '';
            app.text112 = uilabel(app.uipanel1);
            app.text112.Tag = 'text112';
            app.text112.Text = '';
            app.text113 = uilabel(app.uipanel1);
            app.text113.Tag = 'text113';
            app.text113.Text = '';
            app.text114 = uilabel(app.uipanel1);
            app.text114.Tag = 'text114';
            app.text114.Text = '';
            app.text115 = uilabel(app.uipanel1);
            app.text115.Tag = 'text115';
            app.text115.Text = '';
            app.text116 = uilabel(app.uipanel1);
            app.text116.Tag = 'text116';
            app.text116.Text = '';
            app.text117 = uilabel(app.uipanel1);
            app.text117.Tag = 'text117';
            app.text117.Text = '';
            app.text118 = uilabel(app.uipanel1);
            app.text118.Tag = 'text118';
            app.text118.Text = '';
            app.text119 = uilabel(app.uipanel1);
            app.text119.Tag = 'text119';
            app.text119.Text = '';
            app.text120 = uilabel(app.uipanel1);
            app.text120.Tag = 'text120';
            app.text120.Text = '';
            app.text121 = uilabel(app.uipanel1);
            app.text121.Tag = 'text121';
            app.text121.Text = '';
            app.name = uieditfield(app.uipanel1, 'text');
            app.name.Tag = 'name';
            app.name.Value = 'Enter Matric No.';
            app.slider3 = uislider(app.uipanel1);
            app.slider3.Limits = [-15 15];
            app.slider3.MajorTicks = [];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @slider3_Callback, true);
            app.slider3.MinorTicks = [];
            app.slider3.Tag = 'slider3';
            app.slider3.Enable = 'off';
            app.text125 = uilabel(app.uipanel1);
            app.text125.Tag = 'text125';
            app.text125.Text = 'Round:';
            app.roundnumber = uilabel(app.uipanel1);
            app.roundnumber.Tag = 'roundnumber';
            app.roundnumber.Text = '';
            app.text127 = uilabel(app.uipanel1);
            app.text127.Tag = 'text127';
            app.text127.Text = 'Song:';
            app.songnumber = uilabel(app.uipanel1);
            app.songnumber.Tag = 'songnumber';
            app.songnumber.Text = '';
            app.text129 = uilabel(app.uipanel1);
            app.text129.Tag = 'text129';
            app.text129.Text = 'Instructions:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Equalizer_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Equalizer_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end