classdef myrealtime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GainindbSlider_3       matlab.ui.control.Slider
        GainindbSlider_3Label  matlab.ui.control.Label
        GainindbSlider_2       matlab.ui.control.Slider
        GainindbSlider_2Label  matlab.ui.control.Label
        toLabel_3              matlab.ui.control.Label
        EditField_6            matlab.ui.control.NumericEditField
        EditField_5            matlab.ui.control.NumericEditField
        toLabel_2              matlab.ui.control.Label
        EditField_4            matlab.ui.control.NumericEditField
        EditField_3            matlab.ui.control.NumericEditField
        toLabel                matlab.ui.control.Label
        EditField_2            matlab.ui.control.NumericEditField
        EditField_1            matlab.ui.control.NumericEditField
        GainindbSlider_1       matlab.ui.control.Slider
        GainindbSliderLabel    matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        CloseButton            matlab.ui.control.Button
        StopButton             matlab.ui.control.Button
        PlayButton             matlab.ui.control.Button
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        FileNameLabel          matlab.ui.control.Label
        BrowseButton           matlab.ui.control.Button
        PathNameLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        %Properties that corresponds to app functionality
        isStop = 0;
    end
    
    methods (Access = private)
        
        function filter(app)
            global sound;
            global pathname_text;
            
            isStop = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global sound
            global pathname_text
            global filename
            
            [filename, pathname] = uigetfile({'*.wav;*.flac;*.mp3','Supported audio file';...
                '*.wav','Waveform Audio File Format (*.wav)';...
                '*.flac','Free Lossless Audio Codec (*.flac)';...
                '*.mp3','MPEG 1 Layer3 (*.mp3)';...
                '*.*','All files (*.*)'});
                
                pathname_text = strcat(pathname,filename);
                app.Label.Text=filename;
                app.Label_2.Text=pathname_text;
           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global filename

            fileReader = dsp.AudioFileReader(filename, 'SamplesPerFrame', 1024);
            deviceWriter = audioDeviceWriter('SampleRate',fileReader.SampleRate);
            fs = fileReader.SampleRate;
            %FIRLowPass = dsp.LowpassFilter('PassbandFrequency',f1,'StopbandFrequency',f2);

            order = 16; %not sure about order
            total = 0;
            f1 = app.EditField_1.Value;
            f2 = app.EditField_2.Value;
            f3 = app.EditField_3.Value;
            f4 = app.EditField_4.Value;
            f5 = app.EditField_5.Value;
            f6 = app.EditField_6.Value;
            g1 = app.GainindbSlider_1.Value;
            g2 = app.GainindbSlider_2.Value;
            g3 = app.GainindbSlider_3.Value;
            
            a = fir1(order,(f1-1)/(fs/2),'low');        
            b1 = fir1(order,[f1/(fs/2) f2/(fs/2)],'bandpass');
            b2 = fir1(order,[f3/(fs/2) f4/(fs/2)],'bandpass');
            b3 = fir1(order,[f5/(fs/2) f6/(fs/2)],'bandpass');
            c = fir1(order,(f2+1)/(fs/2),'high');
           
            scope = dsp.SpectrumAnalyzer( ...
                'SampleRate',fs, ...
                'PlotAsTwoSidedSpectrum',false, ...
                'FrequencyScale','Log', ...
                'FrequencyResolutionMethod','WindowLength', ...
                'WindowLength',1024, ...
                'Title','Plot', ...
                'ShowLegend',true, ...
                'ChannelNames',{'Original signal','Filtered signal'});

            while ~isDone(fileReader)
                %y = FIRLowPass(x);
                x = fileReader();

                y1 = 1*filter(a,1,x);
                y2 = g1*filter(b1,1,x);
                y3 = g2*filter(b2,1,x);
                y4 = g3*filter(b3,1,x);
                y5 = 1*filter(c,1,x);
                
                total = y1+y2+y3+y4+y5;
                scope([x(:,1),total(:,1)]);

                deviceWriter(total());

                if app.EditField_1.Value ~= f1 ||...
                        app.EditField_2.Value ~= f2 ||...
                        app.EditField_3.Value ~= f3 ||...
                        app.EditField_4.Value ~= f4 ||...
                        app.EditField_5.Value ~= f5 ||...
                        app.EditField_6.Value ~= f6 ||...
                        app.GainindbSlider_1.Value ~= g1 ||...
                        app.GainindbSlider_2.Value ~= g2 ||...
                        app.GainindbSlider_3.Value ~= g3
                    
                    f1 = app.EditField_1.Value;
                    f2 = app.EditField_2.Value;
                    f3 = app.EditField_3.Value;
                    f4 = app.EditField_4.Value;
                    f5 = app.EditField_5.Value;
                    f6 = app.EditField_6.Value;
                    g1 = app.GainindbSlider_1.Value;
                    g2 = app.GainindbSlider_2.Value;
                    g3 = app.GainindbSlider_3.Value;
                end

                if app.isStop == 1
                    release(fileReader);
                    release(deviceWriter);
                    release(scope);
                    %app.isStop = 0;
                end

            end

            release(scope);
            release(fileReader);
            release(deviceWriter);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isStop = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.PathNameLabel = uilabel(app.Panel);
            app.PathNameLabel.Text = 'PathName:';
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileNameLabel = uilabel(app.Panel);
            app.FileNameLabel.Text = 'FileName:';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.GainindbSliderLabel = uilabel(app.UIFigure);
            app.GainindbSliderLabel.Text = 'Gain in db';
            app.GainindbSlider_1 = uislider(app.UIFigure);
            app.GainindbSlider_1.Limits = [-20 20];
            app.GainindbSlider_1.Value = 1;
            app.EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_1.Value = 20;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 30;
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 31;
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 50;
            app.toLabel_2 = uilabel(app.UIFigure);
            app.toLabel_2.Text = 'to';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 51;
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 100;
            app.toLabel_3 = uilabel(app.UIFigure);
            app.toLabel_3.Text = 'to';
            app.GainindbSlider_2Label = uilabel(app.UIFigure);
            app.GainindbSlider_2Label.Text = 'Gain in db';
            app.GainindbSlider_2 = uislider(app.UIFigure);
            app.GainindbSlider_2.Limits = [-20 20];
            app.GainindbSlider_2.Value = 1;
            app.GainindbSlider_3Label = uilabel(app.UIFigure);
            app.GainindbSlider_3Label.Text = 'Gain in db';
            app.GainindbSlider_3 = uislider(app.UIFigure);
            app.GainindbSlider_3.Limits = [-20 20];
            app.GainindbSlider_3.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myrealtime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end