classdef myEQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        toLabel_3          matlab.ui.control.Label
        toLabel_2          matlab.ui.control.Label
        toLabel            matlab.ui.control.Label
        EditField_6        matlab.ui.control.NumericEditField
        EditField_5        matlab.ui.control.NumericEditField
        EditField_4        matlab.ui.control.NumericEditField
        EditField_3        matlab.ui.control.NumericEditField
        EditField_2        matlab.ui.control.NumericEditField
        EditField_1        matlab.ui.control.NumericEditField
        Slider_3           matlab.ui.control.Slider
        Slider3Label       matlab.ui.control.Label
        Slider_2           matlab.ui.control.Slider
        Slider2Label       matlab.ui.control.Label
        Slider             matlab.ui.control.Slider
        SliderLabel        matlab.ui.control.Label
        SettingPanel       matlab.ui.container.Panel
        CloseButton        matlab.ui.control.Button
        VolumeSlider       matlab.ui.control.Slider
        VolumeSliderLabel  matlab.ui.control.Label
        StopButton         matlab.ui.control.Button
        PauseButton        matlab.ui.control.Button
        PlayButton         matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        FileNameLabel      matlab.ui.control.Label
        BrowseButton       matlab.ui.control.Button
        PathNameLabel      matlab.ui.control.Label
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function play_equalizer(app)
            global sound;
            global pathname_text;
            global y_save
            global fs_save
            
            [x, fs] = audioread(pathname_text);
            Volume = app.VolumeSlider.Value;
            g1=app.Slider.Value;
            g2=app.Slider_2.Value;
            g3=app.Slider_3.Value;

            order = 16;
            
            %cut off low pass
            f1=app.EditField_1.Value;
            cut_off=(f1-1); 
            a=fir1(order,cut_off/(fs/2),'low');
            y1=1*filter(a,1,x);

            % %bandpass1
            f1=app.EditField_1.Value;
            f2=app.EditField_2.Value;
            b1=fir1(order,[f1/(fs/2) f2/(fs/2)],'bandpass');
            y2=g1*filter(b1,1,x);

            % %bandpass2
            f3=app.EditField_3.Value;
            f4=app.EditField_4.Value;
            b2=fir1(order,[f3/(fs/2) f4/(fs/2)],'bandpass');
            y3=g2*filter(b2,1,x);

            % %bandpass3
            f5=app.EditField_5.Value;
            f6=app.EditField_6.Value;
            b3=fir1(order,[f5/(fs/2) f6/(fs/2)],'bandpass');
            y4=g3*filter(b3,1,x);

            %highpass
            f6=app.EditField_6.Value;
            cut_off2=f6+1;
            c=fir1(order,cut_off2/(fs/2),'high');
            y5=1*filter(c,1,x);

            handles.yT=y1+y2+y3+y4+y5;
            y_save=Volume*handles.yT;
            fs_save=fs;
            sound = audioplayer(y_save, fs_save);

            clc;

            ax2=app.UIAxes_2;
            plot(ax2,y_save);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global sound
            global pathname_text
            global y_save
            global fs_save
            
            [filename, pathname] = uigetfile({'*.wav;*.flac;*.mp3','Supported audio file';...
                '*.wav','Waveform Audio File Format (*.wav)';...
                '*.flac','Free Lossless Audio Codec (*.flac)';...
                '*.mp3','MPEG 1 Layer3 (*.mp3)';...
                '*.*','All files (*.*)'});

            if isequal(filename,0) || isequal(pathname,0)
                sound = 0;
                return
            else
                [x, fs] = audioread([pathname,filename]); 
                % x:signal  fs:rate
                sound=audioplayer(x, fs);
                pathname_text = strcat(pathname,filename);

                app.Label.Text=filename;
                app.Label_2.Text=pathname_text;

                ax=app.UIAxes;
                plot(ax,x);
                y_save=x;
                fs_save=fs;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global sound
            global playingmusic
            global PnS

            if sound == 0
                return
            else
                playingmusic = 1;
                PnS = 0;
                play_equalizer(app);
                playblocking(sound);
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            global sound
            global playingmusic
            global PnS

            if playingmusic == 1
                if PnS == 0
                    PnS=1;
                    pause(sound);
                else
                    PnS=0;
                    resume(sound);
                end
            else
                return;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global sound
            global playingmusic
            global PnS

            playingmusic = 0;
            PnS = 0;

            stop(sound);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            global sound
            sound = 0;
            delete(app.UIFigure);   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Fitler')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.PathNameLabel = uilabel(app.Panel);
            app.PathNameLabel.Text = 'PathName:';
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileNameLabel = uilabel(app.Panel);
            app.FileNameLabel.Text = 'FileName:';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.SettingPanel = uipanel(app.UIFigure);
            app.SettingPanel.Title = 'Setting';
            app.PlayButton = uibutton(app.SettingPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.SettingPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.SettingPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VolumeSliderLabel = uilabel(app.SettingPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.SettingPanel);
            app.VolumeSlider.Limits = [0 1];
            app.VolumeSlider.Value = 0.5;
            app.CloseButton = uibutton(app.SettingPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-20 20];
            app.Slider.Value = 1;
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-20 20];
            app.Slider_2.Value = 1;
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider3';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-20 20];
            app.Slider_3.Value = 1;
            app.EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to';
            app.toLabel_2 = uilabel(app.UIFigure);
            app.toLabel_2.Text = 'to';
            app.toLabel_3 = uilabel(app.UIFigure);
            app.toLabel_3.Text = 'to';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myEQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end